classdef file_select < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        FileFig            matlab.ui.Figure
        sel_files          matlab.ui.control.ListBox
        pwd_files          matlab.ui.control.ListBox
        pwd_dirs           matlab.ui.control.ListBox
        PREVDropDown       matlab.ui.control.DropDown
        PREVDropDownLabel  matlab.ui.control.Label
        file_label         matlab.ui.control.Label
        CDEditField        matlab.ui.control.EditField
        CDEditFieldLabel   matlab.ui.control.Label
        FilterEditField    matlab.ui.control.EditField
        FilterButton       matlab.ui.control.Button
        DoneButton         matlab.ui.control.Button
        ResetButton        matlab.ui.control.Button
        pwdListMenu        matlab.ui.container.ContextMenu
        SelectAllMenu      matlab.ui.container.Menu
        SelFileMenu        matlab.ui.container.ContextMenu
        RemoveAllMenu      matlab.ui.container.Menu
    end

    
    properties (Access = private)
        callerApp % Main App
        selIm % Image to Find
        rootDir % Starting Directory
        filter % File Filter
        files = {}; % Selected Files
    end
    
    methods (Access = private)
        
        function updateFiles(app,rootDir,filter)
            % Update File List 
            pwdfdr = dir(rootDir); % Search Dir
            app.pwd_dirs.Items = {pwdfdr([pwdfdr.isdir] == 1 & ~startsWith({pwdfdr.name},'.')).name}; % Update Dirs
            pwdfdr = dir(fullfile(rootDir,filter)); % Search Dir
            app.pwd_files.Items = {pwdfdr([pwdfdr.isdir] == 0 & ~startsWith({pwdfdr.name},'.')).name}; % Update Files
        end

        function prev = updateDir(app,rootDir,filter)
            app.CDEditField.Value = rootDir; % Set Dir
            app.FilterEditField.Value = filter; % Set Filter

            % Get Directory Levels
            levels = strsplit(rootDir,filesep);

            % Create List of Previous Directories
            tmp = rootDir; % Set Temp
            prev = cell(length(levels),1); % pre-allocate
            for i = 1 : length(levels)
                prev{i} = tmp; % Add to List
                tmp = fileparts(tmp); % Down One
            end; clear tmp;
            app.PREVDropDown.Items = prev; % Update App
            
            updateFiles(app,rootDir,filter);
        end
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, callerApp, selIm, rootDir, filter)
            app.callerApp = callerApp;
            app.selIm = selIm;
            if nargin<3
                app.rootDir = pwd; 
                app.filter = '*.*';
            else
                app.rootDir = rootDir; 
                app.filter = filter;
            end
            app.updateDir(app.rootDir,app.filter); % Update Component
            app.files = cell(1,callerApp.N);
            app.sel_files.Items = cellfun(@(x) ['S' num2str(x,'%04.f') ...
                ': No ' app.selIm 's selected ...'], ...
                num2cell(1:length(app.files)),'uni',0);
        end

        % Value changed function: CDEditField
        function CDEditFieldValueChanged(app, event)
            value = app.CDEditField.Value;
            app.rootDir = value; % Update rootDir
            app.updateDir(app.rootDir,app.filter); % Update Components
        end

        % Value changed function: PREVDropDown
        function PREVDropDownValueChanged(app, event)
            value = app.PREVDropDown.Value;
            app.rootDir = value; % Update rootDir
            app.updateDir(app.rootDir,app.filter); % Update Components
        end

        % Value changed function: pwd_dirs
        function pwd_dirsValueChanged(app, event)
            value = app.pwd_dirs.Value;
            app.rootDir = fullfile(app.rootDir,value); % Update rootDir
            app.updateDir(app.rootDir,app.filter); % Update Components
        end

        % Value changed function: pwd_files
        function pwd_filesValueChanged(app, event)
            value = app.pwd_files.Value;
            app.files = sort([fullfile(app.rootDir,value)]);
            app.sel_files.Items = app.files;
            app.file_label.Text = [num2str(length(app.files)), ...
                ' of ' num2str(app.callerApp.N), ...
                ' ' app.selIm '(s) Selected'];
            app.pwd_files.Items(strcmp(app.pwd_files.Items,value)) = [];
        end

        % Value changed function: sel_files
        function sel_filesValueChanged(app, event)
            value = app.sel_files.Value;
            file = strsplit(value,filesep);
            app.pwd_files.Items = sort([app.pwd_files.Items file(end)]);
            app.files(strcmp(app.files,value)) = [];
            app.sel_files.Items = app.files;
            app.file_label.Text = [num2str(length(app.files)), ...
                ' of ' num2str(app.callerApp.N), ...
                ' ' app.selIm '(s) Selected'];
        end

        % Menu selected function: SelectAllMenu
        function SelectAllMenuSelected(app, event)
            items = cellfun(@(x) fullfile(app.rootDir,x), ...
                    app.pwd_files.Items,'uni',0);
            if length(items) == app.callerApp.N
                app.files = sort(items); % Replace with List
                app.sel_files.Items = cellfun(@(x,y) [x,y], ...
                    cellfun(@(x) ['S' num2str(x,'%04.f') ': '], ...
                    num2cell(1:length(app.files)),'uni',0), ...
                    app.files,'uni',0);
            elseif length(items) < app.callerApp.N
                app.files = sort([app.files, items]); % Append to List
                app.sel_files.Items = [cellfun(@(x,y) [x,y], ...
                    cellfun(@(x) ['S' num2str(x,'%04.f') ': '], ...
                    num2cell(1:length(app.files)),'uni',0), ...
                    app.files,'uni',0); 
                    cellfun(@(x) ['S' num2str(x,'%04.f') ': None'], ...
                    num2cell(length(app.files)+1:app.callerApp.N),'uni',0)];
            else
                return; % too many files selected
            end
            app.pwd_files.Items = {};
            app.file_label.Text = [num2str(length(app.files)), ...
                ' of ' num2str(app.callerApp.N), ...
                ' ' app.selIm '(s) Selected'];
        end

        % Menu selected function: RemoveAllMenu
        function RemoveAllMenuSelected(app, event)
            app.pwd_files.Items = cellfun(@(x) x{end}, ...
                cellfun(@(x) strsplit(x,filesep), ...
                app.sel_files.Items,'uni',0),'uni',0);
            app.files = {}; 
            app.sel_files.Items = cellfun(@(x) ['S' num2str(x,'%04.f') ...
                ': No ' app.selIm ' selected ...'], ...
                1:app.callerApp.N,'uni',0);
            app.file_label.Text = [num2str(length(app.files)), ...
                ' of ' num2str(app.callerApp.N), ...
                ' ' app.selIm '(s) Selected'];
        end

        % Button pushed function: FilterButton
        function FilterButtonPushed(app, event)
            app.filter = app.FilterEditField.Value;
            app.updateFiles(app.rootDir,app.filter);
        end

        % Button pushed function: ResetButton
        function ResetButtonPushed(app, event)
            app.rootDir = pwd; app.filter = '*.*';
            app.updateFiles(app.rootDir,app.filter);
        end

        % Button pushed function: DoneButton
        function DoneButtonPushed(app, event)
            if isempty(app.callerApp.data.anat) % Create Data Struct
                app.callerApp.data.anat.(app.selIm) = struct(); 
            end

            % There might be a way to avoid the for-loop ... MUST OPTIMIZE
            f = waitbar(0,['Grabbing ' length(app.files) ' files ...'], ...
                'Name','Grabbing Files ...');
            for i = 1:length(app.files)
                app.callerApp.data.anat(i).(app.selIm) = app.files{i};
                waitbar(i/length(app.files),f, ...
                    sprintf('%.0f%% Complete',(i/length(app.files))*100));
            end; close(f);
            app.callerApp.importStatus = 1;
            delete(app);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.FileFig = uifigure('Visible', 'off');
            app.FileFig.Name = 'File Select';
            app.FileFig.Icon = fullfile(pathToMLAPP, 'jbrain_icon.png');
            app.ResetButton = uibutton(app.FileFig, 'push');
            app.ResetButton.ButtonPushedFcn = createCallbackFcn(app, @ResetButtonPushed, true);
            app.ResetButton.Text = 'Reset';
            app.DoneButton = uibutton(app.FileFig, 'push');
            app.DoneButton.ButtonPushedFcn = createCallbackFcn(app, @DoneButtonPushed, true);
            app.DoneButton.Text = 'Done';
            app.FilterButton = uibutton(app.FileFig, 'push');
            app.FilterButton.ButtonPushedFcn = createCallbackFcn(app, @FilterButtonPushed, true);
            app.FilterButton.Text = 'Filter';
            app.FilterEditField = uieditfield(app.FileFig, 'text');
            app.CDEditFieldLabel = uilabel(app.FileFig);
            app.CDEditFieldLabel.Text = 'CD';
            app.CDEditField = uieditfield(app.FileFig, 'text');
            app.CDEditField.ValueChangedFcn = createCallbackFcn(app, @CDEditFieldValueChanged, true);
            app.file_label = uilabel(app.FileFig);
            app.file_label.Text = 'No Files Selected';
            app.PREVDropDownLabel = uilabel(app.FileFig);
            app.PREVDropDownLabel.Text = 'PREV';
            app.PREVDropDown = uidropdown(app.FileFig);
            app.PREVDropDown.Items = {};
            app.PREVDropDown.ValueChangedFcn = createCallbackFcn(app, @PREVDropDownValueChanged, true);
            app.PREVDropDown.Value = {};
            app.pwd_dirs = uilistbox(app.FileFig);
            app.pwd_dirs.Items = {};
            app.pwd_dirs.ValueChangedFcn = createCallbackFcn(app, @pwd_dirsValueChanged, true);
            app.pwd_dirs.Value = {};
            app.pwd_files = uilistbox(app.FileFig);
            app.pwd_files.Items = {};
            app.pwd_files.ValueChangedFcn = createCallbackFcn(app, @pwd_filesValueChanged, true);
            app.pwd_files.Value = {};
            app.sel_files = uilistbox(app.FileFig);
            app.sel_files.Items = {};
            app.sel_files.ValueChangedFcn = createCallbackFcn(app, @sel_filesValueChanged, true);
            app.sel_files.Value = {};
            app.pwdListMenu = uicontextmenu(app.FileFig);
            app.SelectAllMenu = uimenu(app.pwdListMenu);
            app.SelectAllMenu.MenuSelectedFcn = createCallbackFcn(app, @SelectAllMenuSelected, true);
            app.SelectAllMenu.Text = 'Select All';
            % Assign app.pwdListMenu
            app.pwd_files.ContextMenu = app.pwdListMenu;
            app.SelFileMenu = uicontextmenu(app.FileFig);
            app.RemoveAllMenu = uimenu(app.SelFileMenu);
            app.RemoveAllMenu.MenuSelectedFcn = createCallbackFcn(app, @RemoveAllMenuSelected, true);
            app.RemoveAllMenu.Text = 'Remove All';
            % Assign app.SelFileMenu
            app.sel_files.ContextMenu = app.SelFileMenu;
            % Show the figure after all components are created
            app.FileFig.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = file_select(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.FileFig)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.FileFig)
        end
    end
end