classdef roaster < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        AppFig           matlab.ui.Figure
        FileMenu         matlab.ui.container.Menu
        NewProjectMenu   matlab.ui.container.Menu
        OpenProjectMenu  matlab.ui.container.Menu
        SaveProjectMenu  matlab.ui.container.Menu
        HelpMenu         matlab.ui.container.Menu
        ContactUsMenu    matlab.ui.container.Menu
        LogBox           matlab.ui.control.TextArea
        RunROASTButton   matlab.ui.control.Button
        SettingsTable    matlab.ui.control.Table
        NumData          matlab.ui.control.EditField
        NLabel           matlab.ui.control.Label
        DataTree         matlab.ui.container.Tree
        AnatomyDataNode  matlab.ui.container.TreeNode
        Plotter          matlab.ui.control.UIAxes
        AnatomyMenu      matlab.ui.container.ContextMenu
        ImportMenu       matlab.ui.container.Menu
        ImportSel        matlab.ui.container.Menu
        ImportAll        matlab.ui.container.Menu
        RemoveMenu       matlab.ui.container.Menu
        RmSel            matlab.ui.container.Menu
        RmAll            matlab.ui.container.Menu
        AddNodeMenu      matlab.ui.container.Menu
        PlotMenu         matlab.ui.container.Menu
    end

    % Global Variables
    properties (Access = public)
        nodes = struct([]); % Data Tree Nodes
        N = []; % N of Data
        rootDir = fileparts(fileparts(fileparts(mfilename("fullpath")))); % Path to App
        data = struct(); % Data Structure
        cond = table(); % Conductivity Values
        projFile = []; % Abs Path to ProjectFile
        projTag = []; % Project Name
        condStatus = 0;
        importStatus = 0;
    end

    % Helper Functions
    methods (Access = private)

        % Logging Function
        function log2box(app,msg)

            % New Log
            new = sprintf('[%s] %s', ...
                char(datetime('now','TimeZone','local','Format', ...
                'd-MMM-y HH:mm:ss Z')),msg); 
            
            % Limit the maximum length of the TextArea content
            maxLength = 1000;
            if length(new) > maxLength
                new = new(end - maxLength + 1 : end);
            end
    
            % Log with Carriage Return
            if isempty(app.LogBox.Value{end})
                app.LogBox.Value{end} = new; 
            else
                app.LogBox.Value{end+1} = new; 
            end
        end

         % Remove Data Function
        function dataRm(app,node)
            tree = app.(node).Children;
            tree.delete;
        end

        % Add Data Function
        function dataAdd(app,nodeId)
            
            if nodeId > length(app.nodes) % Skip Existing
                % Top Node
                app.nodes(nodeId).base = uitreenode(app.AnatomyDataNode, ...
                    'Text',['S' num2str(nodeId,'%04d')]);

                % Folder Icon
                iconPath = fullfile(app.rootDir,'lib','Resources','unknownicon.gif');

                % Create T1Node
                app.nodes(nodeId).T1 = uitreenode(app.nodes(nodeId).base, ...
                    'Text','T1','Icon',iconPath);

                % Create T2Node
                app.nodes(nodeId).T2 = uitreenode(app.nodes(nodeId).base, ...
                    'Text','T2','Icon',iconPath);

                % Create SegNode
                app.nodes(nodeId).Seg = uitreenode(app.nodes(nodeId).base, ...
                    'Text','Segmentation','Icon',iconPath);

                %             % Red Text
                %             addStyle(app.DataTree,uistyle('FontColor','r'),'node', ...
                %                 [ ...
                %                 app.nodes.(['T1Node' num2str(nodeId)]) ...
                %                 app.nodes.(['T2Node' num2str(nodeId)]) ...
                %                 app.nodes.(['SegNode' num2str(nodeId)]) ...
                %                 ])
            end
        end
        
        % Get Single Anat File Path
        function anatImport(app,selectedNode)
            
            % Check for Anat Image
            selIm = selectedNode.Text; % Anat Type
            if ~ismember({'T1','T2','Segmentation'},selIm); return; end
            nodeId = sscanf(selectedNode.Parent.Text,'S%f'); % Sub

            % Get Filename
            if isfield(app.data.anat,'T1')
                [filename,dirname] = uigetfile('*.nii', ...
                    ['Import ' selIm], ...
                    fileparts(app.data.anat(1).T1));
            elseif isfield(app.data.anat,'T2')
                [filename,dirname] = uigetfile('*.nii', ...
                    ['Import ' selIm], ...
                    fileparts(app.data.anat(1).T2)); 
            elseif isfield(app.data.anat,'Segmentation')
                [filename,dirname] = uigetfile('*.nii', ...
                    ['Import ' selIm], ...
                    fileparts(app.data.anat(1).Segmentation));
            else
                [filename,dirname] = uigetfile('*.nii', ...
                    ['Import ' selIm],app.rootDir); 
            end
            if filename == 0; return; end % No Files Selected :(

            % Add selected File to Data
            if ~isfield(app.data.anat,selIm); app.data.anat.(selIm) = struct(); end
            app.data.anat(nodeId).(selIm) = fullfile(dirname,filename);
             
            % Switch Icon
            iconPath = fullfile(app.rootDir,'lib', ...
                'Resources','HDF_object02.gif');
            selectedNode.Icon = iconPath;
            app.log2box(['Imported ' selIm ' for S' num2str(nodeId,'%04.f') ' !'])
        end
        
        function FEMplot(app,face,node,C)
            % Process Data
            color = nan(size(node,1),1);
            color(C{1}) = sqrt(C{2}.^2+C{3}.^2+C{4}.^2);
            dataShow = [node(:,1:3) color];
            indFace = face(face(:,4) == 2,1:4);
            
            % Plot Data
            plotsurf(dataShow,indFace,'Parent',app.Plotter); 
            shading(app.Plotter,'interp'); 
            clim(app.Plotter,[0 0.4]); 
            colormap(app.Plotter,'turbo'); 
            title(app.Plotter,'Example FEM: F3-F4 @ 2mA')
            axis(app.Plotter,'off'); view(app.Plotter,170,35)
            hc1 = colorbar(app.Plotter); 
            set(hc1,'FontSize',12,'YAxisLocation','right');
            title(hc1,'V/m','FontSize',16);
            zoom(app.Plotter,1.1)
        end
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            log2box(app,'Welcome to ROASTer GUI v1.0 !')
            app.data.anat = struct();

            % Load Data
            load(fullfile(app.rootDir,'lib','Resources', ...
                'exampleMesh.mat'),'face','node')
            fid = fopen(fullfile(app.rootDir, ...
                'lib','Resources','exampleEF.pos'));
            fgetl(fid); C = textscan(fid,'%d %f %f %f'); fclose(fid);

            % Update Settings Table
            app.data.Settings = cell2table({
                'F3','Pad',-2,'70 50 3','lr'; ...
                'F4','Pad',2,'70 50 3','lr'}, ...
                'VariableNames', {'ElecLoc', 'ElecType', ...
                'Intensity (mA)', 'ElecSize [l w h] (mm)', ...
                'ElecOri'});
            app.SettingsTable.Data = app.data.Settings;
            
            % Plot
            app.FEMplot(face,node,C)
        end

        % Menu selected function: NewProjectMenu
        function NewProjectMenuSelected(app, event)
            [filename,dirname] = uiputfile({'*.mat','ROAST Project (*.mat)'},'Save Project As','roast-project.mat');
            if filename == 0; return; end
            app.projFile = fullfile(dirname,filename);
            [~,app.projTag] = fileparts(filename);
            if app.N > 0; app.DataTree.Enable = 'on'; end % Enable Data Tree
            N = app.N; % N of Data
            rootDir = app.rootDir; % Path to App
            data = app.data; % Data Structure
            cond = app.cond; % Conductivity Values
            save(app.projFile,'N','rootDir','data','cond');
            log2box(app,['''' app.projTag ''' Created !'])
        end

        % Menu selected function: OpenProjectMenu
        function OpenProjectMenuSelected(app, event)
            [filename,dirname] = uigetfile({'*.mat','ROAST Project (*.mat)'},'Load ROAST Project');
            if filename == 0; return; end
            app.projFile = fullfile(dirname,filename);
            [~,app.projTag] = fileparts(filename);
            load(app.projFile,'N','rootDir','data','cond')
            
            % Set N
            app.NumData.Value = num2str(N);
            app.N = N;

            % Enable Data Tree
            if app.N > 0; app.DataTree.Enable = 'on'; end

            % Create Nodes
            dataRm(app,'AnatomyDataNode') % Start Fresh
            for i = 1 : app.N; app.dataAdd(i); end % Add Data

            % Update App            
            app.rootDir = rootDir; app.cond = cond;
            
            % Folder Icon
            iconPath = fullfile(app.rootDir,'lib', ...
                'Resources','HDF_object02.gif');
            
            % Update UI Icons
            app.data = data;
            if isfield(app.data,'anat')
                for i = 1:length(app.data.anat)
                    if isfield(app.data.anat,'T1')
                        app.nodes(i).T1.Icon = iconPath;
                    end
                    if isfield(app.data.anat,'T2')
                        app.nodes(i).T2.Icon = iconPath;
                    end
                    if isfield(app.data.anat,'Segmentation')
                        app.nodes(i).Segmentation.Icon = iconPath;
                    end
                end
            end

            % Update Settings Table
            app.SettingsTable.Data = app.data.Settings;
            log2box(app,['''' app.projTag ''' Loaded !'])
        end

        % Menu selected function: SaveProjectMenu
        function SaveProjectMenuSelected(app, event)
            % Check for Project File
            if isempty(app.projFile)
                log2box(app,'Please Create A Project File ...')
                return;
            end

            % Get Current Data
            N = app.N; % N of Data
            rootDir = app.rootDir; % Path to App
            data = app.data; % Data Structure
            cond = app.cond; % Conductivity Values
            save(app.projFile,'N','rootDir','data','cond')
            log2box(app,['''' app.projTag ''' Saved !'])
        end

        % Value changed function: NumData
        function NumDataValueChanged(app, event)
            
            % Check for Project File
            if isempty(app.projFile)
                app.NumData.Value = '';
                log2box(app,'Please Create A Project File ...')
                return;
            end

            % Convert to Number
            try
                app.N = str2double(app.NumData.Value);
            catch
                log2box(app,'Please enter a valid number'); 
                return;
            end

            % Check if the input is a positive integer
            if isnan(app.N) || app.N < 1 || mod(app.N, 1) ~= 0
                log2box(app,'Please enter a valid number');
                return;
            end

            % Enable Data Tree
            app.DataTree.Enable = 'on';

            % Remove Extra Nodes
            if app.N < length(app.nodes)
                R = length(app.nodes);
                for i = 1:(R-app.N)
                    app.nodes(end).base.delete; % Remove UI
                    app.nodes(end) = []; % Delete Obj
                end
            end 
            
            % Create Nodes
            f = waitbar(0,['creating ' app.N ' nodes ...'],'Name','Adding Nodes');
            for i = 1 : app.N
                dataAdd(app,i);
                waitbar(i/app.N,f,sprintf('%.0f%% Complete',(i/app.N)*100)); 
            end; close(f); % Add Data
            
            log2box(app,[num2str(app.N) ' Data Node(s) Created !'])
        end

        % Selection changed function: DataTree
        function DataTreeSelectionChanged(app, event)
            selectedNode = app.DataTree.SelectedNodes;
            anatImport(app,selectedNode)
        end

        % Menu selected function: ImportSel
        function ImportSelMenuSelected(app, event)
            % Check for Project File
            if isempty(app.projFile)
                log2box(app,'Please Create A Project File ...')
                return;
            end

            % Get Node Info
            selectedNode = app.DataTree.SelectedNodes;

            % Check for Anatomy Selected
            if ~ismember({'T1','T2','Segmentation'},selectedNode.Text)
                log2box(app,'Please Select an Anatomy Node (e.g., T1) ...')
                return;
            end

            anatImport(app,selectedNode,app.rootDir,'*.nii')
        end

        % Menu selected function: ImportAll
        function ImportAllMenuSelected(app, event)
            % Check for Project File
            if isempty(app.projFile)
                log2box(app,'Please Create A Project File ...')
                return;
            end

            % Get Node Info
            selectedNode = app.DataTree.SelectedNodes;
            nodeId = sscanf(selectedNode.Parent.Text,'S%f');
            selIm = selectedNode.Text;

            % Check for Anatomy Selected
            if ~ismember({'T1','T2','Segmentation'},selIm)
                log2box(app,'Please Select an Anatomy Node (e.g., T1) ...')
                return;
            end

            % Get Filename
            app.importStatus = 0;
            file_select(app,selIm,app.rootDir,'*.nii');
            waitfor(app,'importStatus',1)

            % Switch Icon
            f = waitbar(0,['updating ' app.N ' nodes ...'],'Name','Updating Nodes');
            iconPath = fullfile(app.rootDir,'lib', ...
                'Resources','HDF_object02.gif');
            for i = 1:length(app.nodes)
                switch selIm
                    case 'T1'
                        app.nodes(i).T1.Icon = iconPath;
                    case 'T2'
                        app.nodes(i).T2.Icon = iconPath;
                    case 'Segmentation'
                        app.nodes(i).Segmentation.Icon = iconPath;
                end
                waitbar(i/length(app.nodes),f, ...
                    sprintf('%.0f%% Complete',(i/length(app.nodes))*100));
            end; close(f);
            log2box(app,['Imported ' selIm ' for all nodes !'])
        end

        % Cell edit callback: SettingsTable
        function SettingsTableCellEdit(app, event)
            newData = event.NewData;
            app.data.Settings = newData;
        end

        % Button pushed function: RunROASTButton
        function RunROASTButtonPushed(app, event)
            % Check for Project File
            if isempty(app.projFile)
                log2box(app,'Please Create A Project File ...')
                return;
            end
            
            % Check if Data Imported 
            if ~isfield(app.data,'anat')
                log2box(app,'Please import at least one T1 ...'); 
                return; 
            end
            
            % Data Directory
            dataDir = fullfile(fileparts(app.projFile),app.projTag);
            if ~exist(dataDir,'dir'); mkdir(dataDir); end

            % Copy T1
            f = waitbar(0,'','Name','Copying Files ...');
            for i = 1:length(app.data.anat)
                if ~exist(fullfile(dataDir,['S' num2str(i,'%04.f')]),'dir')
                    mkdir(fullfile(dataDir,['S' num2str(i,'%04.f')]))
                    log2box(app,['Data Directory Created: ' fullfile(dataDir,['S' num2str(i,'%04.f')])])
                end
                if ~exist(fullfile(dataDir,['S' num2str(i,'%04.f')],'T1.nii'),'file')
                    copyfile(app.data.anat(i).T1, ...
                        fullfile(dataDir,['S' num2str(i,'%04.f')],'T1.nii'),'f')
                end
                waitbar(i/length(app.data.anat),f, ...
                    sprintf('%.0f%% Complete',(i/length(app.data.anat))*100));
            end

            % Copy T2
            if isfield(app.data.anat,'T2')
                for i = 1:length(app.data)
                    if ~exist(fullfile(dataDir,['S' num2str(i,'%04.f')]),'dir')
                        mkdir(fullfile(dataDir,['S' num2str(i,'%04.f')]))
                    end
                    if ~exist(fullfile(dataDir,['S' num2str(i,'%04.f')],'T2.nii'),'file')
                        copyfile(app.data.anat(i).T2, ...
                            fullfile(dataDir,['S' num2str(i,'%04.f')],'T2.nii'),'f')
                    end
                end
                waitbar(i/length(app.data.anat),f, ...
                    sprintf('%.0f%% Complete',(i/length(app.data.anat))*100));
            end; close(f);

            % Default Conductivity of ROAST
            conduc = struct('white',0.126,'gray',0.276,'csf',1.65,'bone',0.01,...
                'skin',0.465,'air',2.5e-14,'gel',0.3,'electrode',5.9e7); % literature values

            % Copy Segmentation
            if isfield(app.data.anat,'Segmentation')

                % Make Project Cond Dir
                condDir = fullfile(dataDir,'conductivites');
                if ~exist(condDir,'dir'); mkdir(condDir); end

                % Get Tissue Conductivity
                condFile = fullfile(condDir,[app.projTag '_cond.mat']);
                if ~exist(condFile,'file')
                    % Get Segmentation
                    nii = niftiread(app.data.anat(1).Segmentation);
                    fnii = nii(:); clear nii;
                    tisId = sort(unique(fnii(fnii ~= 0 & ~isnan(fnii))));

                    % Set Conducitivty
                    app.condStatus = 0;
                    setCond(app,tisId); % setCond UI
                    if isempty(app.cond); return; end % Ended
                    waitfor(app,'condStatus',1); % uiwait

                    % Convert to Struct for ROAST
                    conduc = table2struct(array2table(app.cond{:,4}','VariableNames',app.cond{:,2})); % convert to struct for ROAST
                    conduc.gel = 1; conduc.electrode = 2.5e7;  % HARDCODED                                                       % HARDCODED ELEC CONDUCTIVITY
                    conduc.index = app.cond{:,1}; % Get Unique Tissue Indexes
                    conduc.brain = app.cond{:,3}; % Boolean Index of Brain vs Non-brain
                    save(condFile,'conduc'); % Save
                else
                    load(condFile,'conduc'); % Load
                    log2box(app,[num2str(length(fieldnames(conduc))-4),...
                        '-tissue Conductivities Loaded !'])
                end

                f = waitbar(0,'','Name','Copying MORE Files ...');
                for i = 1:length(app.data.anat)
                    if ~exist(fullfile(dataDir,['S' num2str(i,'%04.f')]),'dir')
                        mkdir(fullfile(dataDir,['S' num2str(i,'%04.f')]))
                    end
                    if exist(fullfile(dataDir, ...
                            ['S' num2str(i,'%04.f')],'T2.nii'),'file')
                        if ~exist(fullfile(dataDir, ...
                            ['S' num2str(i,'%04.f')],'T1_T1andT2_masks.nii'),'file')
                            copyfile(app.data.anat(i).Segmentation, ...
                                fullfile(dataDir, ['S' num2str(i,'%04.f')], ...
                                'T1_T1andT2_masks.nii'),'f');
                        end
                    else
                        if ~exist(fullfile(dataDir, ...
                            ['S' num2str(i,'%04.f')],'T1_T1orT2_masks.nii'),'file')
                            copyfile(app.data.anat(i).Segmentation, ...
                                fullfile(dataDir, ['S' num2str(i,'%04.f')], ...
                                'T1_T1orT2_masks.nii'),'f');
                        end
                    end
                    waitbar(i/length(app.data.anat),f, ...
                        sprintf('%.0f%% Complete', ...
                        (i/length(app.data.anat))*100));
                end; close(f);
            end

            % Repeat Gel Conductivity for each electrode
            if length(conduc.gel(:))==1
                conduc.gel = repmat(conduc.gel, ...
                    1,length(app.data.Settings{:,1}));
            end

            % Repeat Electrode Conductivity for each Electrode
            if length(conduc.electrode(:))==1
                conduc.electrode = repmat(conduc.electrode, ...
                    1,length(app.data.Settings{:,1}));
            end

            % Run ROAST
            datafdr = dir(fullfile(dataDir,'S*'));

            % Create ROAST Recipe
            tmp = table2cell(app.data.Settings(:,[1 3]))';
            Nelec = length(app.data.Settings{:,1}); recipe = cell(1,Nelec*2); 
            for i = 1:numel(tmp); recipe{i} = tmp{i}; end; clear tmp;

            % Format ROAST Settings
            elecType = cell(1,Nelec);
            elecSize = cell(1,Nelec);
            elecOri = cell(1,Nelec);
            for i = 1 : Nelec
                elecType{i} = app.data.Settings{i,2}{1};
                elecSize{i} = eval(['[',app.data.Settings{i,4}{1},']']);
                elecOri{i} = app.data.Settings{i,5}{1};
            end
            log2box(app, ...
                sprintf('ROASTing %dcm %s electrodes @ %.1fmA Total ...', ...
                prod(elecSize{1}(1:2))/100, ...
                app.data.Settings{:,2}{1}, ...
                sum(abs(app.data.Settings{:,3}))));
            % ROAST Loop
            tag = app.projTag;
            parfor i = 1:length(datafdr)
                sDir = datafdr(i).name; 

                % Check for T2
                T2 = fullfile(dataDir,sDir,'T2.nii');
                if ~exist(T2,'file'); T2 = []; end
                try
                    roast4gui(i,fullfile(dataDir,sDir,'T1.nii'), ...
                        recipe, 'elecType', elecType, ...
                        'elecSize', elecSize, 'elecOri', elecOri, ...
                        'T2', T2, 'conductivities',conduc, ...
                        'simulationTag',tag)
                catch ME
                    disp(ME.message)
                end
            end
            log2box(app,'ROAST Complete !')
        end

        % Menu selected function: RmAll
        function RmAllMenuSelected(app, event)
            app.dataRm('AnatomyDataNode');
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.AppFig = uifigure('Visible', 'off');
            app.AppFig.Name = 'ROASTER GUI';
            app.AppFig.Icon = fullfile(pathToMLAPP, 'jbrain_icon.png');
            app.FileMenu = uimenu(app.AppFig);
            app.FileMenu.Text = 'File';
            app.NewProjectMenu = uimenu(app.FileMenu);
            app.NewProjectMenu.MenuSelectedFcn = createCallbackFcn(app, @NewProjectMenuSelected, true);
            app.NewProjectMenu.Text = 'New Project';
            app.OpenProjectMenu = uimenu(app.FileMenu);
            app.OpenProjectMenu.MenuSelectedFcn = createCallbackFcn(app, @OpenProjectMenuSelected, true);
            app.OpenProjectMenu.Accelerator = 'o';
            app.OpenProjectMenu.Text = 'Open Project';
            app.SaveProjectMenu = uimenu(app.FileMenu);
            app.SaveProjectMenu.MenuSelectedFcn = createCallbackFcn(app, @SaveProjectMenuSelected, true);
            app.SaveProjectMenu.Accelerator = 's';
            app.SaveProjectMenu.Text = 'Save Project';
            app.HelpMenu = uimenu(app.AppFig);
            app.HelpMenu.Text = 'Help';
            app.ContactUsMenu = uimenu(app.HelpMenu);
            app.ContactUsMenu.Text = 'Contact Us';
            app.Plotter = uiaxes(app.AppFig);
            xlabel(app.Plotter, 'X')
            ylabel(app.Plotter, 'Y')
            zlabel(app.Plotter, 'Z')
            app.DataTree = uitree(app.AppFig);
            app.DataTree.SelectionChangedFcn = createCallbackFcn(app, @DataTreeSelectionChanged, true);
            app.DataTree.Enable = 'off';
            app.AnatomyDataNode = uitreenode(app.DataTree);
            app.AnatomyDataNode.Icon = fullfile(pathToMLAPP, 'foldericon.gif');
            app.AnatomyDataNode.Text = 'Anatomy Data';
            app.NLabel = uilabel(app.AppFig);
            app.NLabel.Text = 'N =';
            app.NumData = uieditfield(app.AppFig, 'text');
            app.NumData.ValueChangedFcn = createCallbackFcn(app, @NumDataValueChanged, true);
            app.SettingsTable = uitable(app.AppFig);
            app.SettingsTable.ColumnName = {'ElecLoc'; 'ElecType'; 'Intensity (mA)'; 'ElecSize [l w h] (mm)'; 'ElecOri'};
            app.SettingsTable.RowName = {};
            app.SettingsTable.ColumnEditable = true;
            app.SettingsTable.CellEditCallback = createCallbackFcn(app, @SettingsTableCellEdit, true);
            app.RunROASTButton = uibutton(app.AppFig, 'push');
            app.RunROASTButton.ButtonPushedFcn = createCallbackFcn(app, @RunROASTButtonPushed, true);
            app.RunROASTButton.Icon = fullfile(pathToMLAPP, 'greenarrowicon.gif');
            app.RunROASTButton.Text = 'Run ROAST';
            app.LogBox = uitextarea(app.AppFig);
            app.LogBox.Editable = 'off';
            app.AnatomyMenu = uicontextmenu(app.AppFig);
            app.ImportMenu = uimenu(app.AnatomyMenu);
            app.ImportMenu.Text = 'Import';
            app.ImportSel = uimenu(app.ImportMenu);
            app.ImportSel.MenuSelectedFcn = createCallbackFcn(app, @ImportSelMenuSelected, true);
            app.ImportSel.Text = 'Selected';
            app.ImportAll = uimenu(app.ImportMenu);
            app.ImportAll.MenuSelectedFcn = createCallbackFcn(app, @ImportAllMenuSelected, true);
            app.ImportAll.Text = 'All';
            app.RemoveMenu = uimenu(app.AnatomyMenu);
            app.RemoveMenu.Text = 'Remove';
            app.RmSel = uimenu(app.RemoveMenu);
            app.RmSel.Text = 'Selected';
            app.RmAll = uimenu(app.RemoveMenu);
            app.RmAll.MenuSelectedFcn = createCallbackFcn(app, @RmAllMenuSelected, true);
            app.RmAll.Text = 'All';
            app.AddNodeMenu = uimenu(app.AnatomyMenu);
            app.AddNodeMenu.Text = 'Add Node';
            app.PlotMenu = uimenu(app.AnatomyMenu);
            app.PlotMenu.Text = 'Plot';
            % Assign app.AnatomyMenu
            app.DataTree.ContextMenu = app.AnatomyMenu;
            app.AnatomyDataNode.ContextMenu = app.AnatomyMenu;
            % Show the figure after all components are created
            app.AppFig.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = roaster

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.AppFig)

                % Execute the startup function
                runStartupFcn(app, @startupFcn)
            else

                % Focus the running singleton app
                figure(runningApp.AppFig)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.AppFig)
        end
    end
end