classdef setCond < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        CondFig                    matlab.ui.Figure
        TissueConductivitiesLabel  matlab.ui.control.Label
        ConfirmButton              matlab.ui.control.Button
        CondTab                    matlab.ui.control.Table
    end

    
    properties (Access = private)
        callerApp % Main App
        tisId = []; % Tissue IDs
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, callerApp, tisId)
            % Get Main App
            app.callerApp = callerApp;
            
            % Set Default Data
            app.tisId = tisId;
            data = cell(length(tisId),4);
            data(:,1) = num2cell(tisId);
            data(:,2) = {''};
            data(:,3) = num2cell(zeros(length(tisId),1) == 1);
            data(:,4) = num2cell(zeros(length(tisId),1));
            data = cell2table(data,'VariableNames', ...
                {'Index','Name','Brain','Conductivities'});
            app.CondTab.Data = data;
        end

        % Button pushed function: ConfirmButton
        function ConfirmButtonPushed(app, event)
            % Send Data to Main
            app.callerApp.cond = app.CondTab.Data; 
            app.callerApp.condStatus = 1; % uiresume
            delete(app);  % Close App
        end

        % Close request function: CondFig
        function CondFigCloseRequest(app, event)
            app.callerApp.condStatus = 1; % uiresume
            delete(app); % Close App
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.CondFig = uifigure('Visible', 'off');
            app.CondFig.Name = 'CONDUCTIVITY';
            app.CondFig.Icon = fullfile(pathToMLAPP, 'jbrain_icon.png');
            app.CondFig.CloseRequestFcn = createCallbackFcn(app, @CondFigCloseRequest, true);
            app.CondTab = uitable(app.CondFig);
            app.CondTab.ColumnName = {'Index'; 'Name'; 'Brain'; 'Conductivity'};
            app.CondTab.RowName = {};
            app.CondTab.ColumnEditable = [false true true true];
            app.ConfirmButton = uibutton(app.CondFig, 'push');
            app.ConfirmButton.ButtonPushedFcn = createCallbackFcn(app, @ConfirmButtonPushed, true);
            app.ConfirmButton.Icon = fullfile(pathToMLAPP, 'greenarrowicon.gif');
            app.ConfirmButton.Text = 'Confirm';
            app.TissueConductivitiesLabel = uilabel(app.CondFig);
            app.TissueConductivitiesLabel.Text = 'Tissue Conductivities';
            % Show the figure after all components are created
            app.CondFig.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = setCond(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.CondFig)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.CondFig)
        end
    end
end